classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes_3                       matlab.ui.control.UIAxes
        a1Label                        matlab.ui.control.Label
        a2Label                        matlab.ui.control.Label
        b1Label                        matlab.ui.control.Label
        b2Label                        matlab.ui.control.Label
        tmLabel                        matlab.ui.control.Label
        r0Label                        matlab.ui.control.Label
        f0Label                        matlab.ui.control.Label
        a1                             matlab.ui.control.EditField
        a2                             matlab.ui.control.EditField
        b1                             matlab.ui.control.EditField
        b2                             matlab.ui.control.EditField
        tm                             matlab.ui.control.EditField
        r0                             matlab.ui.control.EditField
        f0                             matlab.ui.control.EditField
        CalculateButton                matlab.ui.control.Button
        PredetorPreyCLASSICModelLabel  matlab.ui.control.Label
        ddtra1rb1rfLabel               matlab.ui.control.Label
        ddtfa2fb2rfLabel               matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Switch                         matlab.ui.control.Switch
        AnimationLabel                 matlab.ui.control.Label
        stop                           matlab.ui.control.CheckBox
    end

    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            Ir0=str2double(app.r0.Value);
            If0=str2double(app.f0.Value);
            Itm=str2double(app.tm.Value);
            Ia1=str2double(app.a1.Value);
            Ib1=str2double(app.b1.Value);
            Ia2=str2double(app.a2.Value);
            Ib2=str2double(app.b2.Value);
            
               
            [yy,t]=RK4_1(@Predetor_Prey1,Ir0,If0,0,Itm,500,Ia1,Ib1,Ia2,Ib2);
            plot(app.UIAxes,yy(:,1),yy(:,2),'*');
            app.UIAxes.XTickLabel=yy(:,1);
            app.UIAxes.YTickLabel=yy(:,2);
            plot(app.UIAxes_3,t,yy(:,1),'r');
            app.UIAxes_3.XTickLabel=t(1,:);
            app.UIAxes_3.YTickLabel=yy(:,2);
            plot(app.UIAxes_2,t,yy(:,2));
            app.UIAxes_2.XTickLabel=t(1,:);
            app.UIAxes_2.YTickLabel=yy(:,1);
            if app.Switch.Value=="On"
                    a=length(yy(:,1));
                    figure(1);
                   for i=1:10:a
                          r=fix(yy(i,1));
                          f=fix(yy(i,2));
                          subplot(121);
                          scatter(rand(f,1),rand(f,1),20,'b','o');hold on;
                          title(['Time=' num2str(i/50) ' ;Foxes=' num2str(f) ]);
                          subplot(122);
                          scatter(rand(r,1),rand(r,1),20,'r','d');
                          title([' Rabits=' num2str(r)]);
                          pause(1);
                          if (i~=100)   
                                clf;
                          end
                          disp([i f r]);
                          if app.stop.Value==true
                              break
                          end
                   end
                   close all;
            end
            
        end

        % Callback function
        function StopButtonPushed(app, event)
            return;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Rabbits')
            ylabel(app.UIAxes, 'Foxes')
            app.UIAxes.XTick = [0 100 200 300 400 500];
            app.UIAxes.XTickLabel = {'0'; '100'; '200'; '300'; '400'; '500'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Foxes')
            app.UIAxes_2.XTick = [0 2 4 6 8 10 12 14 16 18 20];
            app.UIAxes_2.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.UIAxes_2.YTick = [0 200 300 400 500];
            app.UIAxes_2.YTickLabel = {'0'; '200'; '300'; '400'; '500'};
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Rabbits')
            app.UIAxes_3.XTick = [0 2 4 6 8 10 12 14 16 18 20];
            app.UIAxes_3.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'; '12'; '14'; '16'; '18'; '20'};
            app.UIAxes_3.YTick = [0 200 300 400 500];
            app.UIAxes_3.YTickLabel = {'0'; '200'; '300'; '400'; '500'};
            app.a1Label = uilabel(app.UIFigure);
            app.a1Label.Text = 'a1 =';
            app.a2Label = uilabel(app.UIFigure);
            app.a2Label.Text = 'a2 =';
            app.b1Label = uilabel(app.UIFigure);
            app.b1Label.Text = 'b1 =';
            app.b2Label = uilabel(app.UIFigure);
            app.b2Label.Text = 'b2 =';
            app.tmLabel = uilabel(app.UIFigure);
            app.tmLabel.Text = 'tm =';
            app.r0Label = uilabel(app.UIFigure);
            app.r0Label.Text = 'r0 =';
            app.f0Label = uilabel(app.UIFigure);
            app.f0Label.Text = 'f0 =';
            app.a1 = uieditfield(app.UIFigure, 'text');
            app.a1.Value = '1';
            app.a2 = uieditfield(app.UIFigure, 'text');
            app.a2.Value = '1';
            app.b1 = uieditfield(app.UIFigure, 'text');
            app.b1.Value = '0.005';
            app.b2 = uieditfield(app.UIFigure, 'text');
            app.b2.Value = '0.005';
            app.tm = uieditfield(app.UIFigure, 'text');
            app.tm.Value = '20';
            app.r0 = uieditfield(app.UIFigure, 'text');
            app.r0.Value = '500';
            app.f0 = uieditfield(app.UIFigure, 'text');
            app.f0.Value = '350';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.PredetorPreyCLASSICModelLabel = uilabel(app.UIFigure);
            app.PredetorPreyCLASSICModelLabel.Text = 'Predetor-Prey CLASSIC Model';
            app.ddtra1rb1rfLabel = uilabel(app.UIFigure);
            app.ddtra1rb1rfLabel.Text = 'd/dt(r)=a1*r - b1*rf';
            app.ddtfa2fb2rfLabel = uilabel(app.UIFigure);
            app.ddtfa2fb2rfLabel.Text = 'd/dt(f)=-a2*f + b2*rf';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.AnimationLabel = uilabel(app.UIFigure);
            app.AnimationLabel.Text = 'Animation';
            app.stop = uicheckbox(app.UIFigure);
            app.stop.Text = 'Stop animation';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end